classdef prueba < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PnduloInvertidoUIFigure         matlab.ui.Figure
        RunButton                       matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        PosicinTab                      matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        VelocidadTab                    matlab.ui.container.Tab
        UIAxes2                         matlab.ui.control.UIAxes
        GuardardatosTab                 matlab.ui.container.Tab
        RutadelarchivoEditFieldLabel    matlab.ui.control.Label
        RutadelarchivoEditField         matlab.ui.control.EditField
        GuardarButton                   matlab.ui.control.Button
        NombredelarchivoEditFieldLabel  matlab.ui.control.Label
        NombredelarchivoEditField       matlab.ui.control.EditField
        NOTALosdatossonguardadosenunarchivoconextensinCSVLabel  matlab.ui.control.Label
        SeleccionarButton               matlab.ui.control.Button
        PosicindeseadacmEditFieldLabel  matlab.ui.control.Label
        PosicindeseadacmEditField       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function RunButtonPushed(app, event)
            run("fcn_transf_pend.m")
            plot(app.UIAxes,t1,y1,'Color','[1 0 0]');
            plot(app.UIAxes2,t2,y2,'Color','[0 0 1]');
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            %Guardar datos en csv
            try
                filename = app.RutadelarchivoEditField.Value + "\" + app.NombredelarchivoEditField.Value + ".csv";
                run("fcn_transf_pend.m")
                varNames = {'Tiempo','Posicion pendulo','Posicion carrito'};
                contenido = table(t1,y1,y2,'VariableNames',varNames);
                try
                    writetable(contenido, filename)
                catch
                    errordlg("No ha ingresado el nombre del archivo","Error");
                end
            catch
            end
        end

        % Button pushed function: SeleccionarButton
        function SeleccionarButtonPushed(app, event)
            %Seleccionar ruta
            path = uigetdir('C:\');
            app.RutadelarchivoEditField.Value = path;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PnduloInvertidoUIFigure = uifigure('Visible', 'off');
            app.PnduloInvertidoUIFigure.Name = 'Péndulo Invertido';
            app.RunButton = uibutton(app.PnduloInvertidoUIFigure, 'push');
            app.RunButton.Tag = 'btnGraficar';
            app.RunButton.Text = 'Run';
            app.TabGroup = uitabgroup(app.PnduloInvertidoUIFigure);
            app.PosicinTab = uitab(app.TabGroup);
            app.PosicinTab.Title = 'Posición';
            app.UIAxes = uiaxes(app.PosicinTab);
            title(app.UIAxes, 'Posición')
            xlabel(app.UIAxes, 't [s]')
            ylabel(app.UIAxes, 'X [cm]')
            app.VelocidadTab = uitab(app.TabGroup);
            app.VelocidadTab.Title = 'Velocidad';
            app.UIAxes2 = uiaxes(app.VelocidadTab);
            title(app.UIAxes2, 'Velocidad')
            xlabel(app.UIAxes2, 't [s]')
            ylabel(app.UIAxes2, 'v [m/s]')
            app.GuardardatosTab = uitab(app.TabGroup);
            app.GuardardatosTab.Title = 'Guardar datos';
            app.RutadelarchivoEditFieldLabel = uilabel(app.GuardardatosTab);
            app.RutadelarchivoEditFieldLabel.Text = 'Ruta del archivo';
            app.RutadelarchivoEditField = uieditfield(app.GuardardatosTab, 'text');
            app.GuardarButton = uibutton(app.GuardardatosTab, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Text = 'Guardar';
            app.NombredelarchivoEditFieldLabel = uilabel(app.GuardardatosTab);
            app.NombredelarchivoEditFieldLabel.Text = 'Nombre del archivo';
            app.NombredelarchivoEditField = uieditfield(app.GuardardatosTab, 'text');
            app.NOTALosdatossonguardadosenunarchivoconextensinCSVLabel = uilabel(app.GuardardatosTab);
            app.NOTALosdatossonguardadosenunarchivoconextensinCSVLabel.Text = 'NOTA: Los datos son guardados en un archivo con extensión CSV';
            app.SeleccionarButton = uibutton(app.GuardardatosTab, 'push');
            app.SeleccionarButton.ButtonPushedFcn = createCallbackFcn(app, @SeleccionarButtonPushed, true);
            app.SeleccionarButton.Text = 'Seleccionar';
            app.PosicindeseadacmEditFieldLabel = uilabel(app.PnduloInvertidoUIFigure);
            app.PosicindeseadacmEditFieldLabel.Text = 'Posición deseada [cm]';
            app.PosicindeseadacmEditField = uieditfield(app.PnduloInvertidoUIFigure, 'numeric');
            % Show the figure after all components are created
            app.PnduloInvertidoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = prueba

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PnduloInvertidoUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PnduloInvertidoUIFigure)
        end
    end
end